classdef proyecto < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        AnalisisdeImagenesDigitalesLabel  matlab.ui.control.Label
        SeleccionarButton  matlab.ui.control.Button
        IdentificarButton  matlab.ui.control.Button
        RESETButton        matlab.ui.control.Button
        UIAxes             matlab.ui.control.UIAxes
        UIAxes2            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        img % Description
        img2 % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SeleccionarButton
        function select_img(app, event)
            [file,path] = uigetfile('*.*'); %cualquier archivo a seleccionar
            if isequal(file,0)
                figure(app.UIFigure);
                return;
            end
            figure(app.UIFigure);
            app.img = imread(fullfile(path,file));
            title(app.UIAxes,"Input");
            imshow(app.img,'Parent',app.UIAxes);
            imageSize = size(app.img);
            xLimitsToShow = [1, imageSize(2) / 4]; % Define the x-axis limits to show
            yLimitsToShow = [1, imageSize(1) / 4]; % Define the y-axis limits to show
            app.UIAxes.XLim = xLimitsToShow; % If you are using App Designer
            app.UIAxes.YLim = yLimitsToShow; % If you are using App Designer
            app.UIAxes.Position(3:4) = imageSize(2:-1:1); % If you are using App Designer
            axis(app.UIAxes, 'equal'); % If you are using App Designer
        end

        % Button pushed function: IdentificarButton
        function convert(app, event)
            
            app.img2 = rgb2gray(app.img);
            

            %--------------------------------------------
            % detectar bordes
            %--------------------------------------------
            edge_detected = edge(app.img2,'canny',.2);
            title(app.UIAxes2,"Output");
            imshow(edge_detected,'parent',app.UIAxes2);
        end

        % Button pushed function: RESETButton
        function reset(app, event)
            axesObj = app.UIAxes;
            axesObj2 = app.UIAxes2;
            cla(axesObj);
            cla(axesObj2);
            app.img = "";
            app.img2 = "";
            title(app.UIAxes," ");
            title(app.UIAxes2," ");
        end

        % Button down function: UIAxes
        function original_image(app, event)
            [file,path] = uigetfile('*.*'); %cualquier archivo a seleccionar
            if isequal(file,0)
                figure(app.UIFigure);
                return;
            end
            figure(app.UIFigure);
            app.img = imread(fullfile(path,file));
            title(app.UIAxes,"Input");
            imshow(app.img,'Parent',app.UIAxes);
            imageSize = size(app.img);
            xLimitsToShow = [1, imageSize(2) / 4]; % Define the x-axis limits to show
            yLimitsToShow = [1, imageSize(1) / 4]; % Define the y-axis limits to show
            app.UIAxes.XLim = xLimitsToShow; % If you are using App Designer
            app.UIAxes.YLim = yLimitsToShow; % If you are using App Designer
            app.UIAxes.Position(3:4) = imageSize(2:-1:1); % If you are using App Designer
            axis(app.UIAxes, 'equal'); % If you are using App Designer
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AnalisisdeImagenesDigitalesLabel = uilabel(app.UIFigure);
            app.AnalisisdeImagenesDigitalesLabel.Text = 'Analisis de Imagenes Digitales';
            app.SeleccionarButton = uibutton(app.UIFigure, 'push');
            app.SeleccionarButton.ButtonPushedFcn = createCallbackFcn(app, @select_img, true);
            app.SeleccionarButton.Text = 'Seleccionar';
            app.IdentificarButton = uibutton(app.UIFigure, 'push');
            app.IdentificarButton.ButtonPushedFcn = createCallbackFcn(app, @convert, true);
            app.IdentificarButton.Text = 'Identificar';
            app.RESETButton = uibutton(app.UIFigure, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @reset, true);
            app.RESETButton.Text = 'RESET';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Input')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Visible = 'off';
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @original_image, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Output')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = proyecto

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end